classdef PROYECTOFINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CALCULARButton                 matlab.ui.control.Button
        RESULTADOSTextArea             matlab.ui.control.TextArea
        Label_2                        matlab.ui.control.Label
        RESULTADOSTextAreaLabel        matlab.ui.control.Label
        MTODODEBISECCINLabel           matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TOLERANCIAMXIMAEditField       matlab.ui.control.NumericEditField
        TOLERANCIAMXIMAEditFieldLabel  matlab.ui.control.Label
        LMITESUPERIOREditField         matlab.ui.control.NumericEditField
        LMITESUPERIOREditFieldLabel    matlab.ui.control.Label
        LMITEINFERIOREditField         matlab.ui.control.NumericEditField
        LMITEINFERIOREditFieldLabel    matlab.ui.control.Label
        FUNCINEditField                matlab.ui.control.EditField
        FUNCINEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FUNCINEditField
        function FUNCINEditFieldValueChanged(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            
        end

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
         % Obtener valores de los campos de texto y numéricos
            func = app.FUNCINEditField.Value; % Obtener la función ingresada
            f = str2func(['@(x)' func]); % Convertir la función a función anónima
            a = app.LMITEINFERIOREditField.Value; % Límite inferior del intervalo
            b = app.LMITESUPERIOREditField.Value; % Límite superior del intervalo
            tol = app.TOLERANCIAMXIMAEditField.Value; % Tolerancia máxima
            
            % Comprobación inicial del teorema de Bolzano
            if f(a) * f(b) > 0
                app.RESULTADOSTextArea.Value = 'No se cumple el teorema de Bolzano (f(a)*f(b) > 0)';
                return;
            end
            
            % Inicialización de variables
            cont = 0;
            results = ''; % Variable para almacenar los resultados
            
            % Método de bisección
            while abs(b - a) > tol
                m = (a + b) / 2; % Punto medio del intervalo
                
                % Evaluar la función en a, b y m
                fa = f(a);
                fb = f(b);
                fm = f(m);
                
                % Determinar el nuevo intervalo
                if fa * fm < 0
                    b = m; % La raíz está en el intervalo [a, m]
                elseif fm * fb < 0
                    a = m; % La raíz está en el intervalo [m, b]
                end
                
                % Contador de iteraciones
                cont = cont + 1;
                
                % Guardar los resultados
                results = sprintf('%s\nIteración %d, intervalo [%f, %f]', results, cont, a, b);
                
                % Mostrar resultados en la TextArea
                app.RESULTADOSTextArea.Value = results;
                drawnow; % Actualizar la interfaz gráfica
                
                pause(0.1); % Dar un breve tiempo de espera para visualizar la iteración
            end
            
            % Mostrar la raíz encontrada
            root = (a + b) / 2;
            results = sprintf('%s\nLa raíz es %f', results, root);
            app.RESULTADOSTextArea.Value = results;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FUNCINEditFieldLabel = uilabel(app.UIFigure);
            app.FUNCINEditFieldLabel.Text = 'FUNCIÓN';
            app.FUNCINEditField = uieditfield(app.UIFigure, 'text');
            app.FUNCINEditField.ValueChangedFcn = createCallbackFcn(app, @FUNCINEditFieldValueChanged, true);
            app.LMITEINFERIOREditFieldLabel = uilabel(app.UIFigure);
            app.LMITEINFERIOREditFieldLabel.Text = 'LÍMITE INFERIOR';
            app.LMITEINFERIOREditField = uieditfield(app.UIFigure, 'numeric');
            app.LMITESUPERIOREditFieldLabel = uilabel(app.UIFigure);
            app.LMITESUPERIOREditFieldLabel.Text = 'LÍMITE SUPERIOR';
            app.LMITESUPERIOREditField = uieditfield(app.UIFigure, 'numeric');
            app.TOLERANCIAMXIMAEditFieldLabel = uilabel(app.UIFigure);
            app.TOLERANCIAMXIMAEditFieldLabel.Text = 'TOLERANCIA MÁXIMA';
            app.TOLERANCIAMXIMAEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MTODODEBISECCINLabel = uilabel(app.UIFigure);
            app.MTODODEBISECCINLabel.Text = 'MÉTODO DE BISECCIÓN';
            app.RESULTADOSTextAreaLabel = uilabel(app.UIFigure);
            app.RESULTADOSTextAreaLabel.Text = 'RESULTADOS';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.RESULTADOSTextArea = uitextarea(app.UIFigure);
            app.CALCULARButton = uibutton(app.UIFigure, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROYECTOFINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end